classdef project2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        OpenImageButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Image1Tab                       matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        TabGroup_2                      matlab.ui.container.TabGroup
        Image1Tab_2                     matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        CurrentLabel                    matlab.ui.control.Label
        PreviewLabel                    matlab.ui.control.Label
        SaveResultButton                matlab.ui.control.Button
        GeneratePreviewButton           matlab.ui.control.Button
        ApplyChangesButton              matlab.ui.control.Button
        SpatialDomainFilterOptionsDropDownLabel  matlab.ui.control.Label
        SpatialDomainFilterOptionsDropDown  matlab.ui.control.DropDown
        NeighborhoodSizeButtonGroup     matlab.ui.container.ButtonGroup
        x3Button                        matlab.ui.control.RadioButton
        x5Button                        matlab.ui.control.RadioButton
        x7Button                        matlab.ui.control.RadioButton
        x9Button                        matlab.ui.control.RadioButton
        BrightnessSliderLabel           matlab.ui.control.Label
        BrightnessSlider                matlab.ui.control.Slider
        ContrastSliderLabel             matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
        FrequencyDomainFilterOptionsDropDownLabel  matlab.ui.control.Label
        FrequencyDomainFilterOptionsDropDown  matlab.ui.control.DropDown
        FilterDomainButtonGroup         matlab.ui.container.ButtonGroup
        SpatialDomainButton             matlab.ui.control.RadioButton
        FrequencyDomainButton           matlab.ui.control.RadioButton
        AdditionalFiltersButton         matlab.ui.control.RadioButton
        BoostScalingCoefficientEditFieldLabel  matlab.ui.control.Label
        BoostScalingCoefficientEditField  matlab.ui.control.NumericEditField
        FilterTypeDropDownLabel         matlab.ui.control.Label
        FilterTypeDropDown              matlab.ui.control.DropDown
        ButterworthFilterOrderDropDownLabel  matlab.ui.control.Label
        ButterworthFilterOrderDropDown  matlab.ui.control.DropDown
        FirstCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        FirstCutoffFrequencyEditField   matlab.ui.control.NumericEditField
        SecondCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        SecondCutoffFrequencyEditField  matlab.ui.control.NumericEditField
        AdditionalFilterOptionsDropDownLabel  matlab.ui.control.Label
        AdditionalFilterOptionsDropDown  matlab.ui.control.DropDown
    end

    properties (Access = public)
        imageData % The original image file data
        filterDomain % Specifies what domain the filtering is done in
        filterOption % Specifies what kind of filter is being used
        filterType % Specifies the frequency domain filter type
        brightness % Description
        contrast % Description
        highboostScale
        thresh
        order
        preview % Description
        thresh2 % Description
    end

    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            [file] = uigetfile( ...
                {'*.png;*.jpg;*.jpeg',...
                 'Image Files (*.png;*.jpg;*.jpeg)';
                 '*.*',  'All Files (*.*)'}, ...
                 'Select an Image');
            if file==0
                message = sprintf('Sorry! \n There was an error.');
                uialert(errfig,message,'Error',...
                'Icon','warning');
            else
                app.imageData = imread(file);
                cla(app.UIAxes,'reset')
                imshow(app.imageData,'Parent',app.UIAxes);
                app.UIAxes.XLabel.Visible = 'off';
                app.UIAxes.YLabel.Visible = 'off';
                app.UIAxes.Title.Visible = 'off';
                app.UIAxes.XLim = [0 inf];
                app.UIAxes.YLim = [0 inf];
                app.filterDomain = app.FilterDomainButtonGroup.SelectedObject;
                switch app.filterDomain
                    case app.FrequencyDomainButton
                        app.filterOption = app.SpatialDomainFilterOptionsDropDown.Value;
                    case app.SpatialDomainButton
                        app.filterOption = app.FrequencyDomainFilterOptionsDropDown.Value;
                    case app.AdditionalFiltersButton
                        app.filterOption = app.AdditionalFilterOptionsDropDown.Value;
                end
                app.filterType = app.FilterTypeDropDown.Value;
                app.highboostScale = app.BoostScalingCoefficientEditField.Value;
                app.brightness = app.BrightnessSlider.Value;
                app.contrast = app.ContrastSlider.Value;
                app.thresh = app.FirstCutoffFrequencyEditField.Value;
                app.order = app.ButterworthFilterOrderDropDown.Value;
                app.thresh2 = app.SecondCutoffFrequencyEditField.Value;
            end
        end

        % Value changed function: SpatialDomainFilterOptionsDropDown
        function SpatialDomainFilterOptionsDropDownValueChanged(app, event)
            app.filterOption = app.SpatialDomainFilterOptionsDropDown.Value;
            switch app.filterOption
                case 'None'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                case 'Brightness'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'on';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'on';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    %Stuff
                case 'Contrast'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'on';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'on';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    %Stuff
                case 'Smoothing Low-Pass'  % User selected a defined option
                    app.NeighborhoodSizeButtonGroup.Visible = 'on';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    % Enter Low-Pass Filter data here
                case 'Edge Detect High-Pass'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    % Enter High-Pass Filter data here
                case 'Highboost'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'on';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'on';
                    %Enter Highboost dta here
                case 'Global Histogram-Equalization'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    %enter code here
                case 'Adaptive Histogram-Equalization'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    %entr cde
            end
        end

        % Selection changed function: FilterDomainButtonGroup
        function FilterDomainButtonGroupSelectionChanged(app, event)
            app.filterDomain = app.FilterDomainButtonGroup.SelectedObject;
            switch app.filterDomain
                case app.SpatialDomainButton
                    app.SpatialDomainFilterOptionsDropDown.Value = 'None';
                    app.SpatialDomainFilterOptionsDropDown.Visible = 'on';
                    app.SpatialDomainFilterOptionsDropDownLabel.Visible = 'on';
                    app.FrequencyDomainFilterOptionsDropDown.Visible = 'off';
                    app.FrequencyDomainFilterOptionsDropDownLabel.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    app.FilterTypeDropDown.Visible = 'off';
                    app.FilterTypeDropDownLabel.Visible = 'off';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.FirstCutoffFrequencyEditField.Visible = 'off';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditField.Visible = 'off';
                    app.AdditionalFilterOptionsDropDown.Visible = 'off';
                    app.AdditionalFilterOptionsDropDownLabel.Visible = 'off';
                case app.FrequencyDomainButton
                    app.FrequencyDomainFilterOptionsDropDown.Value = 'None';
                    app.SpatialDomainFilterOptionsDropDown.Visible = 'off';
                    app.SpatialDomainFilterOptionsDropDownLabel.Visible = 'off';
                    app.FrequencyDomainFilterOptionsDropDown.Visible = 'on';
                    app.FrequencyDomainFilterOptionsDropDownLabel.Visible = 'on';
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    app.AdditionalFilterOptionsDropDown.Visible = 'off';
                    app.AdditionalFilterOptionsDropDownLabel.Visible = 'off';
                case app.AdditionalFiltersButton
                    app.SpatialDomainFilterOptionsDropDown.Value = 'None';
                    app.AdditionalFilterOptionsDropDown.Visible = 'on';
                    app.AdditionalFilterOptionsDropDownLabel.Visible = 'on';
                    app.FrequencyDomainFilterOptionsDropDown.Visible = 'off';
                    app.FrequencyDomainFilterOptionsDropDownLabel.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    app.FilterTypeDropDown.Visible = 'off';
                    app.FilterTypeDropDownLabel.Visible = 'off';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.FirstCutoffFrequencyEditField.Visible = 'off';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditField.Visible = 'off';
                    app.FrequencyDomainFilterOptionsDropDown.Value = 'None';
                    app.SpatialDomainFilterOptionsDropDown.Visible = 'off';
                    app.SpatialDomainFilterOptionsDropDownLabel.Visible = 'off';
                    app.FrequencyDomainFilterOptionsDropDown.Visible = 'on';
                    app.FrequencyDomainFilterOptionsDropDownLabel.Visible = 'on';
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
            end
        end

        % Value changed function: FrequencyDomainFilterOptionsDropDown
        function FrequencyDomainFilterOptionsDropDownValueChanged(app, event)
            app.filterOption = app.FrequencyDomainFilterOptionsDropDown.Value;
            switch app.filterOption
                case 'None'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    app.FilterTypeDropDown.Visible = 'off';
                    app.FilterTypeDropDownLabel.Visible = 'off';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.FirstCutoffFrequencyEditField.Visible = 'off';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditField.Visible = 'off';
                case 'Low-Pass'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    app.FilterTypeDropDown.Visible = 'on';
                    app.FilterTypeDropDownLabel.Visible = 'on';
                    app.FilterTypeDropDown.Value = 'None';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditField.Visible = 'on';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditField.Visible = 'off';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    %Stuff
                case 'High-Pass'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    app.FilterTypeDropDown.Visible = 'on';
                    app.FilterTypeDropDownLabel.Visible = 'on';
                    app.FilterTypeDropDown.Value = 'None';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditField.Visible = 'on';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditField.Visible = 'off';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    %Stuff
                case 'Highboost'  % User selected a defined option
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'on';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'on';
                    app.FilterTypeDropDown.Visible = 'on';
                    app.FilterTypeDropDownLabel.Visible = 'on';
                    app.FilterTypeDropDown.Value = 'None';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditField.Visible = 'on';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditField.Visible = 'off';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    % Enter Low-Pass Filter data here
                case 'Band-Pass'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    app.FilterTypeDropDown.Visible = 'on';
                    app.FilterTypeDropDownLabel.Visible = 'on';
                    app.FilterTypeDropDown.Value = 'None';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditField.Visible = 'on';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.SecondCutoffFrequencyEditField.Visible = 'on';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    % Enter High-Pass Filter data here
                case 'Band-Stop'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    app.FilterTypeDropDown.Visible = 'on';
                    app.FilterTypeDropDownLabel.Visible = 'on';
                    app.FilterTypeDropDown.Value = 'None';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditField.Visible = 'on';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.SecondCutoffFrequencyEditField.Visible = 'on';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    %Enter Highboost dta here
                case 'Homomorphic'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    app.FilterTypeDropDown.Visible = 'off';
                    app.FilterTypeDropDownLabel.Visible = 'off';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditField.Visible = 'on';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditField.Visible = 'off';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    %enter code here
            end
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            app.filterType = app.FilterTypeDropDown.Value;
            switch app.filterType
                case 'Ideal'
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    %Blah
                case 'Gaussian'
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    %Blah
                case 'Butterworth'
                    app.ButterworthFilterOrderDropDown.Visible = 'on';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'on';
                    %Blah
            end
        end

        % Button pushed function: GeneratePreviewButton
        function GeneratePreviewButtonPushed(app, event)
            disp(app.filterDomain);
            disp(app.filterType);
            disp(app.filterOption);
            reverse = 0;
            switch app.filterDomain
                case app.SpatialDomainButton
                    switch app.filterOption
                        case 'Brightness'
                            fIm = rgb2ntsc(app.imageData);
                            if size(app.imageData,3)==3
                                fIm=rgb2ntsc(app.imageData);
                            else 
                                fIm=double(app.imageData)./255;
                            end
                            fIm(:,:,1)=fIm(:,:,1)+app.brightness*(1-fIm(:,:,1));
                            if size(app.imageData,3)==3
                                fIm=ntsc2rgb(fIm);
                            end
                        case 'Contrast'
                            fIm = imadjust(app.imageData,stretchlim(app.imageData),[],app.contrast)
                        case 'Smoothing Low-Pass'
                            if app.NeighborhoodSizeButtonGroup.SelectedObject == app.x3Button
                                h = ones(3,3)/9;
                                fIm = imfilter(app.imageData,h);
                            elseif app.NeighborhoodSizeButtonGroup.SelectedObject == app.x5Button
                                h = ones(5,5)/25;
                                fIm = imfilter(app.imageData,h);
                            elseif app.NeighborhoodSizeButtonGroup.SelectedObject == app.x7Button
                                h = ones(7,7)/49;
                                fIm = imfilter(app.imageData,h);
                            elseif app.NeighborhoodSizeButtonGroup.SelectedObject == app.x9Button
                                h = ones(9,9)/81;
                                fIm = imfilter(app.imageData,h);
                            end
                        case 'Edge Detect High-Pass'
                            h = ones(3,3).*-1;
                            h(2,2) = 8;
                            fIm = imfilter(app.imageData,h);
                        case 'Highboost'
                            h = [0 -1 0;-1 5 -1;0 -1 0];
                            h(2,2) = 5+(app.highboostScale-1);
                            fIm = imfilter(app.imageData,h);
                        case 'Global Histogram-Equalization'
                            fIm = histeq(app.imageData);
                        case 'Adaptive Histogram-Equalization'
                            [rows, columns, numberOfColorChannels] = size(app.imageData);
                            if numberOfColorChannels > 1
                                LAB = rgb2lab(app.imageData);
                                L = LAB(:,:,1)/100;
                                L = adapthisteq(L,'NumTiles',[8 8],'ClipLimit',0.005);
                                LAB(:,:,1) = L*100;
                                fIm = lab2rgb(LAB);
                            else
                                fIm = adapthisteq(app.imageData,'clipLimit',0.02,'Distribution','rayleigh');
                            end
                        otherwise
                            f = msgbox('Please choose an option','Error','error');
                            return;
                    end
                case app.FrequencyDomainButton
                    [rows, columns, numberOfColorChannels] = size(app.imageData);
                    if numberOfColorChannels > 1
                        fIm = rgb2ycbcr(app.imageData);
                        h = fIm(:,:,1);
                        h = double(h);
                    else
                        h=double(app.imageData);
                    end

                    %2D fft
                    h2=fft2_centered(h);
                   
                    switch app.filterOption
                        case 'Low-Pass'
                            switch app.filterType
                                case 'Ideal'
                                    h3=ideal_lowpass_centered_freq(h2,app.thresh);
                                case 'Gaussian'
                                    h3=gaussian_low_center_f(h2,app.thresh);
                                case 'Butterworth'
                                    h3=butterworth_low_center_f(h2,app.thresh,app.order);
                                otherwise
                                    f = msgbox('Please choose an option','Error','error');
                                    return;
                            end
                        case 'High-Pass'
                            switch app.filterType
                                case 'Ideal'
                                    h3 = ideal_highpass_centered_freq(h2,app.thresh);
                                case 'Gaussian'
                                    h3 = gaussian_high_center_f(h2,app.thresh);
                                case 'Butterworth'
                                    h3 = butterworth_high_center_f(h2,app.thresh,app.order);
                                otherwise
                                    f = msgbox('Please choose an option','Error','error');
                                    return;
                            end
                        case 'Highboost'
                            
                        case 'Band-Pass'
                            switch app.filterType
                                case 'Ideal'
                                    h3 = ideal_bandpass_centered_freq(h2,app.thresh,app.thresh2);
                                case 'Gaussian'
                                    fIm = ffilter(app.imageData,'Bandpass','Gaussian',app.thresh,app.thresh2);
                                    reverse=1;
                                case 'Butterworth'
                                    disp(app.order);
                                    fIm = ffilter(app.imageData,'Bandpass','Butterworth',app.thresh,app.thresh2,uint8(app.order));
                                    reverse=1;
                                otherwise
                                    f = msgbox('Please choose an option','Error','error');
                                    return;
                            end
                        case 'Band-Stop'
                            switch app.filterType
                                case 'Ideal'
                                    h3 = ideal_bandstop_centered_freq(h2,app.thresh,app.thresh2);
                                case 'Gaussian'
                                    fIm = ffilter(app.imageData,'Bandstop','Gaussian',app.thresh,app.thresh2);
                                    reverse=1;
                                case 'Butterworth'
                                    fIm = ffilter(app.imageData,'Bandstop','Butterworth',app.thresh,app.thresh2,uint8(app.order));
                                    reverse=1;
                                otherwise
                                    f = msgbox('Please choose an option','Error','error');
                                    return;
                            end
                        case 'Homomorphic'
                            h3 = homomorphic_gamma(h2,app.thresh);
                        otherwise
                            f = msgbox('Please choose an option','Error','error');
                            return;
                    end
                    
                    if reverse==0
                        %inverse 2D fft
                        if numberOfColorChannels > 1
                            fIm(:,:,1) = abs(real(ifft2(h3)));
                            fIm = ycbcr2rgb(fIm);
                        else
                            fIm = abs(real(ifft2(h3)));
                        end
                        fIm=uint8(fIm);
                    end
                otherwise
                    f = msgbox('Please choose an option','Error','error');
                    return;
        end
            cla(app.UIAxes_2, 'reset')
            imshow(fIm, 'Parent', app.UIAxes_2);
            app.UIAxes_2.XLabel.Visible = 'off';
            app.UIAxes_2.YLabel.Visible = 'off';
            app.UIAxes_2.Title.Visible = 'off';
            app.UIAxes_2.XLim = [0 inf];
            app.UIAxes_2.YLim = [0 inf];
            app.preview = fIm;
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            app.brightness = app.BrightnessSlider.Value;
            
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            app.contrast = app.ContrastSlider.Value;
            
        end

        % Value changed function: BoostScalingCoefficientEditField
        function BoostScalingCoefficientEditFieldValueChanged(app, event)
            app.highboostScale = app.BoostScalingCoefficientEditField.Value;
            
        end

        % Value changed function: ButterworthFilterOrderDropDown
        function ButterworthFilterOrderDropDownValueChanged(app, event)
            app.order = app.ButterworthFilterOrderDropDown.Value;
            
        end

        % Value changed function: FirstCutoffFrequencyEditField
        function FirstCutoffFrequencyEditFieldValueChanged(app, event)
            app.thresh = app.FirstCutoffFrequencyEditField.Value;
            
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            app.imageData = app.preview;
            cla(app.UIAxes,'reset')
            cla(app.UIAxes_2,'reset')
            app.UIAxes_2.XLabel.Visible = 'off';
            app.UIAxes_2.YLabel.Visible = 'off';
            app.UIAxes_2.Title.Visible = 'off';
            app.UIAxes_2.XLim = [0 inf];
            app.UIAxes_2.YLim = [0 inf];
            imshow(app.imageData, 'Parent', app.UIAxes);
            app.UIAxes.XLabel.Visible = 'off';
            app.UIAxes.YLabel.Visible = 'off';
            app.UIAxes.Title.Visible = 'off';
            app.UIAxes.XLim = [0 inf];
            app.UIAxes.YLim = [0 inf];
        end

        % Value changed function: SecondCutoffFrequencyEditField
        function SecondCutoffFrequencyEditFieldValueChanged(app, event)
            app.thresh2 = app.SecondCutoffFrequencyEditField.Value;
        end

        % Value changed function: AdditionalFilterOptionsDropDown
        function AdditionalFilterOptionsDropDownValueChanged(app, event)
            app.filterType = app.AdditionalFilterOptionsDropDown.Value;
            switch app.filterType
                case 'Ideal'
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    %Blah
                case 'Gaussian'
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    %Blah
                case 'Butterworth'
                    app.ButterworthFilterOrderDropDown.Visible = 'on';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'on';
                    %Blah
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.OpenImageButton = uibutton(app.UIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Image1Tab = uitab(app.TabGroup);
            app.Image1Tab.Title = 'Image 1';
            app.UIAxes = uiaxes(app.Image1Tab);
            title(app.UIAxes, 'Image 1 Current')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.Image1Tab_2 = uitab(app.TabGroup_2);
            app.Image1Tab_2.Title = 'Image 1';
            app.UIAxes_2 = uiaxes(app.Image1Tab_2);
            title(app.UIAxes_2, 'Image 1 Preview')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.CurrentLabel = uilabel(app.UIFigure);
            app.CurrentLabel.Text = 'Current';
            app.PreviewLabel = uilabel(app.UIFigure);
            app.PreviewLabel.Text = 'Preview';
            app.SaveResultButton = uibutton(app.UIFigure, 'push');
            app.SaveResultButton.Text = 'Save Result';
            app.GeneratePreviewButton = uibutton(app.UIFigure, 'push');
            app.GeneratePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePreviewButtonPushed, true);
            app.GeneratePreviewButton.Text = 'Generate Preview';
            app.ApplyChangesButton = uibutton(app.UIFigure, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.SpatialDomainFilterOptionsDropDownLabel = uilabel(app.UIFigure);
            app.SpatialDomainFilterOptionsDropDownLabel.Text = 'Spatial Domain Filter Options';
            app.SpatialDomainFilterOptionsDropDown = uidropdown(app.UIFigure);
            app.SpatialDomainFilterOptionsDropDown.Items = {'None', 'Brightness', 'Contrast', 'Smoothing Low-Pass', 'Edge Detect High-Pass', 'Highboost', 'Global Histogram-Equalization', 'Adaptive Histogram-Equalization'};
            app.SpatialDomainFilterOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @SpatialDomainFilterOptionsDropDownValueChanged, true);
            app.SpatialDomainFilterOptionsDropDown.Value = 'None';
            app.NeighborhoodSizeButtonGroup = uibuttongroup(app.UIFigure);
            app.NeighborhoodSizeButtonGroup.Title = 'Neighborhood Size';
            app.NeighborhoodSizeButtonGroup.Visible = 'off';
            app.x3Button = uiradiobutton(app.NeighborhoodSizeButtonGroup);
            app.x3Button.Text = '3x3';
            app.x3Button.Value = true;
            app.x5Button = uiradiobutton(app.NeighborhoodSizeButtonGroup);
            app.x5Button.Text = '5x5';
            app.x7Button = uiradiobutton(app.NeighborhoodSizeButtonGroup);
            app.x7Button.Text = '7x7';
            app.x9Button = uiradiobutton(app.NeighborhoodSizeButtonGroup);
            app.x9Button.Text = '9x9';
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Visible = 'off';
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.Limits = [0 0.99];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.Visible = 'off';
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Visible = 'off';
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [0 0.99];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Visible = 'off';
            app.FrequencyDomainFilterOptionsDropDownLabel = uilabel(app.UIFigure);
            app.FrequencyDomainFilterOptionsDropDownLabel.Visible = 'off';
            app.FrequencyDomainFilterOptionsDropDownLabel.Text = 'Frequency Domain Filter Options';
            app.FrequencyDomainFilterOptionsDropDown = uidropdown(app.UIFigure);
            app.FrequencyDomainFilterOptionsDropDown.Items = {'None', 'Low-Pass', 'High-Pass', 'Highboost', 'Band-Pass', 'Band-Stop', 'Homomorphic'};
            app.FrequencyDomainFilterOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyDomainFilterOptionsDropDownValueChanged, true);
            app.FrequencyDomainFilterOptionsDropDown.Visible = 'off';
            app.FrequencyDomainFilterOptionsDropDown.Value = 'None';
            app.FilterDomainButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterDomainButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterDomainButtonGroupSelectionChanged, true);
            app.FilterDomainButtonGroup.Title = 'Filter Domain';
            app.SpatialDomainButton = uiradiobutton(app.FilterDomainButtonGroup);
            app.SpatialDomainButton.Text = 'Spatial Domain';
            app.SpatialDomainButton.Value = true;
            app.FrequencyDomainButton = uiradiobutton(app.FilterDomainButtonGroup);
            app.FrequencyDomainButton.Text = 'Frequency Domain';
            app.AdditionalFiltersButton = uiradiobutton(app.FilterDomainButtonGroup);
            app.AdditionalFiltersButton.Text = 'Additional Filters';
            app.BoostScalingCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
            app.BoostScalingCoefficientEditFieldLabel.Text = 'Boost Scaling Coefficient';
            app.BoostScalingCoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoostScalingCoefficientEditField.Limits = [1 3];
            app.BoostScalingCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @BoostScalingCoefficientEditFieldValueChanged, true);
            app.BoostScalingCoefficientEditField.Visible = 'off';
            app.BoostScalingCoefficientEditField.Value = 1;
            app.FilterTypeDropDownLabel = uilabel(app.UIFigure);
            app.FilterTypeDropDownLabel.Visible = 'off';
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.UIFigure);
            app.FilterTypeDropDown.Items = {'None', 'Ideal', 'Gaussian', 'Butterworth'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Visible = 'off';
            app.FilterTypeDropDown.Value = 'None';
            app.ButterworthFilterOrderDropDownLabel = uilabel(app.UIFigure);
            app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
            app.ButterworthFilterOrderDropDownLabel.Text = 'Butterworth Filter Order';
            app.ButterworthFilterOrderDropDown = uidropdown(app.UIFigure);
            app.ButterworthFilterOrderDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.ButterworthFilterOrderDropDown.ValueChangedFcn = createCallbackFcn(app, @ButterworthFilterOrderDropDownValueChanged, true);
            app.ButterworthFilterOrderDropDown.Visible = 'off';
            app.ButterworthFilterOrderDropDown.Value = '1';
            app.FirstCutoffFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FirstCutoffFrequencyEditFieldLabel.Visible = 'off';
            app.FirstCutoffFrequencyEditFieldLabel.Text = 'First Cutoff Frequency';
            app.FirstCutoffFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FirstCutoffFrequencyEditField.Limits = [1e-05 3000];
            app.FirstCutoffFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FirstCutoffFrequencyEditFieldValueChanged, true);
            app.FirstCutoffFrequencyEditField.Visible = 'off';
            app.FirstCutoffFrequencyEditField.Value = 3;
            app.SecondCutoffFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
            app.SecondCutoffFrequencyEditFieldLabel.Text = 'Second Cutoff Frequency';
            app.SecondCutoffFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondCutoffFrequencyEditField.Limits = [0.0001 3000];
            app.SecondCutoffFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SecondCutoffFrequencyEditFieldValueChanged, true);
            app.SecondCutoffFrequencyEditField.Visible = 'off';
            app.SecondCutoffFrequencyEditField.Value = 3;
            app.AdditionalFilterOptionsDropDownLabel = uilabel(app.UIFigure);
            app.AdditionalFilterOptionsDropDownLabel.Text = 'Additional Filter Options';
            app.AdditionalFilterOptionsDropDown = uidropdown(app.UIFigure);
            app.AdditionalFilterOptionsDropDown.Items = {'None', 'Generate Mask', 'Option 1'};
            app.AdditionalFilterOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @AdditionalFilterOptionsDropDownValueChanged, true);
            app.AdditionalFilterOptionsDropDown.Visible = 'off';
            app.AdditionalFilterOptionsDropDown.Value = 'None';
        end
    end

    methods (Access = public)

        % Construct app
        function app = project2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end